classdef maingui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OvalocytesDetectionUIFigure    matlab.ui.Figure
        OvalocytesDetectionUsingImageProcessingLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        LoadSamplesTab                 matlab.ui.container.Tab
        LoadBloodSamplePanel           matlab.ui.container.Panel
        ImportSampleButton             matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        ClearAllButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ProcessSampleTab               matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        ProcessSampleButton            matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        ResultsTab                     matlab.ui.container.Tab
        AnalysisPanel                  matlab.ui.container.Panel
        segmented                      matlab.ui.control.UIAxes
        OutputParametersPanel          matlab.ui.container.Panel
        ElongatedCellEstimatedLabel    matlab.ui.control.Label
        OvaCount                       matlab.ui.control.NumericEditField
        ResetAllButton                 matlab.ui.control.Button
        ExportDataButton               matlab.ui.control.Button
        ExitButton_2                   matlab.ui.control.Button
        TotalNormalCellEstimatedLabel  matlab.ui.control.Label
        RBCcount                       matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        SegmentOvalocytesButton        matlab.ui.control.Button
        SegmentRBCButton               matlab.ui.control.Button
        CountNormalRBCButton           matlab.ui.control.Button
        CountOvalocytesButton          matlab.ui.control.Button
        AboutTab                       matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Input; % Description
        erodeI;
        erodedI;
        a;
        b;
        c;
        d;
        Ova_Count;
        Segmented_RBC;
        WBC_Count;
        green;
        outline;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportSampleButton
        function ImportSampleButtonPushed(app, event)
        [FileName, PathName]=uigetfile('*.jpg','Select an Blood Sample');
        app.Input=imread(strcat(PathName,FileName));
        imshow(app.Input,'parent',app.UIAxes);
        title('Blood Sample','parent',app.UIAxes);
        app.a=1;
        app.UIAxes2.cla
        app.segmented.cla
        app.RBCcount.Value= 0;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            
        end

        % Button pushed function: ProcessSampleButton
        function ProcessSampleButtonPushed(app, event)
        if app.a ==1
            I1 = rgb2gray(app.Input);
            I2 = adapthisteq(I1);
            
            BW = imbinarize(I2,graythresh(I2));
            BW1 = bwareaopen(BW, 1000);
            BW2 = ~BW1;
            
            BW3 = imfill(BW2, 'holes');
            BW4 = bwareaopen(BW3, 1000);
            
            se = strel('disk',5);
            app.erodedI = imerode(BW4,se);
            imshow(app.erodedI,'parent',app.UIAxes2);
            title('Preprocessed Blood Sample','Parent',app.UIAxes2);
            
            subplot(2,2,1);
            imshow(I1);title('Gray Image');
            subplot(2,2,2);
            imshow(I2);title('Contrast enhancement');
            subplot(2,2,3);
            imshow(BW1);title('Small Object Removal');
            subplot(2,2,4);
            imshow(app.erodedI);title('Inverted Binary Image');
            app.b=1;
        else
            msgbox('Import Blood Sample','Error','error');
        end
        end

        % Button pushed function: SegmentOvalocytesButton
        function SegmentOvalocytesButtonPushed(app, event)
        if app.b==1
            Segmented_Ova = bwareafilt(app.erodedI,[6400 50000]);
            
            Segmented_Ova = imfill(Segmented_Ova, 'holes');
            imshow (Segmented_Ova, 'parent',app.segmented);
            title('Ovalocytes Segmented Binary mask','parent',app.segmented);
            
            app.outline=bwperim(Segmented_Ova);
            app.green=zeros(size(app.Input,1),size(app.Input,2),3);
            app.green(:,:,2)=1;
            imshow(app.Input,'parent',app.segmented);
            hold(app.segmented,'on')
            title('Ovalocytes Mask Overlay on Blood Sample','parent',app.segmented);
            se = strel('disk',7);
            Segmented_Ova = imerode(Segmented_Ova,se);
            hold(app.segmented,'on')
            h=imshow(app.green,'parent',app.segmented);
            set(h,'AlphaData',app.outline)
            
            cc= bwconncomp(Segmented_Ova);
            app.Ova_Count = cc.NumObjects;
            app.c=1;
            
        else
            msgbox('Load Blood Sample & Preprocess','Error','error');
        end
        end

        % Button pushed function: SegmentRBCButton
        function SegmentRBCButtonPushed(app, event)
        if app.b==1
            app.Segmented_RBC = bwareafilt(app.erodedI,[400 6400]);
            
            app.Segmented_RBC = imfill(app.Segmented_RBC,'holes');
            imshow(app.Segmented_RBC,'parent',app.segmented);
            
            app.outline=bwperim(app.Segmented_RBC);
            app.green=zeros(size(app.Input,1),size(app.Input,2),3);
            app.green(:,:,2)=1;
            imshow(app.Input,'parent',app.segmented);
            title('RBC Mask Overlay on Blood sample','parent',app.segmented);
            hold(app.segmented,'on')
            h=imshow(app.green,'parent',app.segmented);
            set(h,'AlphaData',app.outline);
            app.d=1;
        else
            msgbox('load Blood Sample & Preprocess','Error','error');
        end
            
        end

        % Button pushed function: CountNormalRBCButton
        function CountNormalRBCButtonPushed(app, event)
        if app.d==1
            
            border=bwperim(app.Segmented_RBC);
            [centres, radii, metric] = imfindcircles(border,[20, 50],'ObjectPolarity','bright','Sensitivity',0.9,'EdgeThreshold',0.1);
            
            imshow(app.Input,'parent',app.segmented);
            [l,c] = size(radii);
            plot(centres(:,1), centres(:,2), 'r*','parent',app.segmented);
            
            Number_of_RBC_cells=length(centres);
            app.RBCcount.Value= Number_of_RBC_cells;
            
        else
            msgbox('First Segment RBC','Error','error');
            
        end
            
        end

        % Value changed function: RBCcount
        function RBCcountValueChanged(app, event)
        if app.c==1
        app.RBCcount.Value=app.RBCcount;
        else
            msgbox('First Segment RBC','Error','error');
        
        end
        end

        % Button pushed function: CountOvalocytesButton
        function CountOvalocytesButtonPushed(app, event)
        if app.c==1
            app.OvaCount.Value=app.Ova_Count;
        else
            msgbox('First Segment Ovalocytes','Error','error');
        end
            
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.RBCcount.Value=0;
            app.OvaCount.Value=0;
            app.segmented.cla;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OvalocytesDetectionUIFigure = uifigure('Visible', 'off');
            app.OvalocytesDetectionUIFigure.Name = 'Ovalocytes Detection';
            app.OvalocytesDetectionUsingImageProcessingLabel = uilabel(app.OvalocytesDetectionUIFigure);
            app.OvalocytesDetectionUsingImageProcessingLabel.Text = 'Ovalocytes Detection Using Image Processing';
            app.TabGroup = uitabgroup(app.OvalocytesDetectionUIFigure);
            app.LoadSamplesTab = uitab(app.TabGroup);
            app.LoadSamplesTab.Title = 'Load Samples';
            app.LoadBloodSamplePanel = uipanel(app.LoadSamplesTab);
            app.LoadBloodSamplePanel.Title = 'Load Blood Sample';
            app.ImportSampleButton = uibutton(app.LoadBloodSamplePanel, 'push');
            app.ImportSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSampleButtonPushed, true);
            app.ImportSampleButton.Text = 'Import Sample';
            app.ExitButton = uibutton(app.LoadBloodSamplePanel, 'push');
            app.ExitButton.Text = 'Exit';
            app.ClearAllButton = uibutton(app.LoadBloodSamplePanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.UIAxes = uiaxes(app.LoadSamplesTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.ProcessSampleTab = uitab(app.TabGroup);
            app.ProcessSampleTab.Title = 'Process Sample';
            app.Panel_2 = uipanel(app.ProcessSampleTab);
            app.ProcessSampleButton = uibutton(app.Panel_2, 'push');
            app.ProcessSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessSampleButtonPushed, true);
            app.ProcessSampleButton.Text = 'Process Sample';
            app.UIAxes2 = uiaxes(app.ProcessSampleTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.AnalysisPanel = uipanel(app.ResultsTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.segmented = uiaxes(app.AnalysisPanel);
            title(app.segmented, '')
            xlabel(app.segmented, '')
            ylabel(app.segmented, '')
            app.segmented.Visible = 'off';
            app.OutputParametersPanel = uipanel(app.ResultsTab);
            app.OutputParametersPanel.Title = 'Output Parameters';
            app.ElongatedCellEstimatedLabel = uilabel(app.OutputParametersPanel);
            app.ElongatedCellEstimatedLabel.Text = 'Elongated Cell Estimated';
            app.OvaCount = uieditfield(app.OutputParametersPanel, 'numeric');
            app.ResetAllButton = uibutton(app.OutputParametersPanel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.ExportDataButton = uibutton(app.OutputParametersPanel, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.ExitButton_2 = uibutton(app.OutputParametersPanel, 'push');
            app.ExitButton_2.Text = 'Exit';
            app.TotalNormalCellEstimatedLabel = uilabel(app.OutputParametersPanel);
            app.TotalNormalCellEstimatedLabel.Text = 'Total Normal Cell Estimated';
            app.RBCcount = uieditfield(app.OutputParametersPanel, 'numeric');
            app.RBCcount.ValueChangedFcn = createCallbackFcn(app, @RBCcountValueChanged, true);
            app.Panel = uipanel(app.ResultsTab);
            app.SegmentOvalocytesButton = uibutton(app.Panel, 'push');
            app.SegmentOvalocytesButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentOvalocytesButtonPushed, true);
            app.SegmentOvalocytesButton.Text = 'Segment Ovalocytes';
            app.SegmentRBCButton = uibutton(app.Panel, 'push');
            app.SegmentRBCButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentRBCButtonPushed, true);
            app.SegmentRBCButton.Text = 'Segment RBC';
            app.CountNormalRBCButton = uibutton(app.Panel, 'push');
            app.CountNormalRBCButton.ButtonPushedFcn = createCallbackFcn(app, @CountNormalRBCButtonPushed, true);
            app.CountNormalRBCButton.Text = 'Count Normal RBC';
            app.CountOvalocytesButton = uibutton(app.Panel, 'push');
            app.CountOvalocytesButton.ButtonPushedFcn = createCallbackFcn(app, @CountOvalocytesButtonPushed, true);
            app.CountOvalocytesButton.Text = 'Count Ovalocytes';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Label = uilabel(app.AboutTab);
            % Show the figure after all components are created
            app.OvalocytesDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maingui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OvalocytesDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OvalocytesDetectionUIFigure)
        end
    end
end